classdef eq_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        HzLabel                       matlab.ui.control.Label
        HzSlider                      matlab.ui.control.Slider
        GenerateButton                matlab.ui.control.Button
        HzLabel_2                     matlab.ui.control.Label
        HzSlider_2                    matlab.ui.control.Slider
        HzLabel_3                     matlab.ui.control.Label
        HzSlider_3                    matlab.ui.control.Slider
        HzLabel_4                     matlab.ui.control.Label
        HzSlider_4                    matlab.ui.control.Slider
        HzSlider_5Label               matlab.ui.control.Label
        HzSlider_5                    matlab.ui.control.Slider
        HzSlider_6Label               matlab.ui.control.Label
        HzSlider_6                    matlab.ui.control.Slider
        HzLabel_5                     matlab.ui.control.Label
        HzSlider_7                    matlab.ui.control.Slider
        StatusReadyLabel              matlab.ui.control.Label
        InputFileLabel                matlab.ui.control.Label
        InputFileEditField            matlab.ui.control.EditField
        ImpulseResponseDropDownLabel  matlab.ui.control.Label
        ImpulseResponseDropDown       matlab.ui.control.DropDown
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        LengthEditFieldLabel          matlab.ui.control.Label
        LengthEditField               matlab.ui.control.NumericEditField
        GainEditFieldLabel            matlab.ui.control.Label
        GainEditField                 matlab.ui.control.NumericEditField
        WindowSizeEditFieldLabel      matlab.ui.control.Label
        WindowSizeEditField           matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            % Update status to Running
            app.StatusReadyLabel.Text = "Status: Running";
            drawnow
            
            % Read file name
            filename = app.InputFileEditField.Value;
            filter_design % generate filters
            
            len = app.LengthEditField.Value; % Read in length
            
            % Read and plot fft of audio file
            [y,Fs] = audioread(filename);
            input_fft = fft(y(:,1));
            stem(app.UIAxes,abs(input_fft));
            drawnow
            
            % Read window size and run test_functions
            windowSize = app.WindowSizeEditField.Value;
            [a, Fs] = test_functions(filename,len,2,1,windowSize,1,-1,-1,100);
            
            a = Second_Gaussian(a,Fs);
            
            impulse_name = app.ImpulseResponseDropDown.Value;
            a = Sharpening_Filter(a,Fs,impulse_name);
            
            % Read in gain values
            gainlp = app.HzSlider.Value;
            gain20_60 = app.HzSlider_2.Value;
            gain60_200 = app.HzSlider_3.Value;
            gain200_600 = app.HzSlider_4.Value;
            gain600_3k = app.HzSlider_5.Value;
            gain3k_8k = app.HzSlider_6.Value;
            gainhp = app.HzSlider_7.Value;
            
            song_1 = a(:,1);
            song_2 = a(:,2);
            
            song_1_gain = zeros(length(song_1),1);
            song_2_gain = zeros(length(song_2),1);
            
            % Add amplification/attenuation if gain is not equal to zero
            % for each frequency band
            if gainlp ~= 0
                song_1_gain = song_1_gain + gainlp*filter(lp20,song_1);
                song_2_gain = song_2_gain + gainlp*filter(lp20,song_2);
            end
            if gain20_60 ~= 0
                song_1_gain = song_1_gain + gain20_60*filter(bp20_60,song_1);
                song_2_gain = song_2_gain + gain20_60*filter(bp20_60,song_2);
            end
            if gain60_200 ~= 0
                song_1_gain = song_1_gain + gain60_200*filter(bp60_200,song_1);
                song_2_gain = song_2_gain + gain60_200*filter(bp60_200,song_2);
            end
            if gain200_600 ~= 0
                song_1_gain = song_1_gain + gain200_600*filter(bp200_600,song_1);
                song_2_gain = song_2_gain + gain200_600*filter(bp200_600,song_2);
            end
            if gain600_3k ~= 0
                song_1_gain = song_1_gain + gain600_3k*filter(bp600_3k,song_1);
                song_2_gain = song_2_gain + gain600_3k*filter(bp600_3k,song_2);
            end
            if gain3k_8k ~= 0
                song_1_gain = song_1_gain + gain3k_8k*filter(bp3k_8k,song_1);
                song_2_gain = song_2_gain + gain3k_8k*filter(bp3k_8k,song_2);
            end
            if gainhp ~= 0
                song_1_gain = song_1_gain + gainhp*filter(hp8k,song_1);
                song_2_gain = song_2_gain + gainhp*filter(hp8k,song_2);
            end
            
            song_1 = song_1 + song_1_gain;
            song_2 = song_2 + song_2_gain;
            
            gain = app.GainEditField.Value;
            song = gain*[ song_1 song_2 ];
            
            % Plot the fft of the output
            output_fft = fft(song(:,1));
            stem(app.UIAxes2,abs(output_fft));
            drawnow
            
            audiowrite('song_output.wav',song,Fs);
            app.StatusReadyLabel.Text = "Status: Completed";
            drawnow
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = {'0-'; '20 Hz'};
            app.HzSlider = uislider(app.UIFigure);
            app.HzSlider.Limits = [-1 3];
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.HzLabel_2 = uilabel(app.UIFigure);
            app.HzLabel_2.Text = {'20-'; '60 Hz'};
            app.HzSlider_2 = uislider(app.UIFigure);
            app.HzSlider_2.Limits = [-1 3];
            app.HzLabel_3 = uilabel(app.UIFigure);
            app.HzLabel_3.Text = {'60-'; '200 Hz'};
            app.HzSlider_3 = uislider(app.UIFigure);
            app.HzSlider_3.Limits = [-1 3];
            app.HzLabel_4 = uilabel(app.UIFigure);
            app.HzLabel_4.Text = {'200-'; '600 Hz'};
            app.HzSlider_4 = uislider(app.UIFigure);
            app.HzSlider_4.Limits = [-1 3];
            app.HzSlider_5Label = uilabel(app.UIFigure);
            app.HzSlider_5Label.Text = {'600-'; '3000 Hz'};
            app.HzSlider_5 = uislider(app.UIFigure);
            app.HzSlider_5.Limits = [-1 3];
            app.HzSlider_6Label = uilabel(app.UIFigure);
            app.HzSlider_6Label.Text = {'3000-'; '8000 Hz'};
            app.HzSlider_6 = uislider(app.UIFigure);
            app.HzSlider_6.Limits = [-1 3];
            app.HzLabel_5 = uilabel(app.UIFigure);
            app.HzLabel_5.Text = {'>8000'; 'Hz'};
            app.HzSlider_7 = uislider(app.UIFigure);
            app.HzSlider_7.Limits = [-1 3];
            app.StatusReadyLabel = uilabel(app.UIFigure);
            app.StatusReadyLabel.Text = 'Status: Ready';
            app.InputFileLabel = uilabel(app.UIFigure);
            app.InputFileLabel.Text = 'Input File';
            app.InputFileEditField = uieditfield(app.UIFigure, 'text');
            app.ImpulseResponseDropDownLabel = uilabel(app.UIFigure);
            app.ImpulseResponseDropDownLabel.Text = {'Impulse'; 'Response'};
            app.ImpulseResponseDropDown = uidropdown(app.UIFigure);
            app.ImpulseResponseDropDown.Items = {'delta_length_720000.wav', 'delta_length_1323000.wav'};
            app.ImpulseResponseDropDown.Value = 'delta_length_720000.wav';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input Magnitude FFT')
            xlabel(app.UIAxes, 'n')
            ylabel(app.UIAxes, '')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Output Magnitude FFT')
            xlabel(app.UIAxes2, 'n')
            ylabel(app.UIAxes2, '')
            app.LengthEditFieldLabel = uilabel(app.UIFigure);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.GainEditFieldLabel = uilabel(app.UIFigure);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.UIFigure, 'numeric');
            app.GainEditField.Value = 1;
            app.WindowSizeEditFieldLabel = uilabel(app.UIFigure);
            app.WindowSizeEditFieldLabel.Text = 'WindowSize';
            app.WindowSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindowSizeEditField.Value = 100000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eq_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end